classdef HONGMK1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_12                      matlab.ui.control.Button
        Button_11                      matlab.ui.control.Button
        TurretIncrement                matlab.ui.control.Button
        TurretDecrement                matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        ShoulderPitchIncrement         matlab.ui.control.Button
        ShoulderPitchDecerment         matlab.ui.control.Button
        EndEffectorSlider              matlab.ui.control.Slider
        EndEffectorSliderLabel         matlab.ui.control.Label
        HONGMK1ControlPanelLabel       matlab.ui.control.Label
        DetectedObjectsEditField       matlab.ui.control.EditField
        DetectedObjectsEditFieldLabel  matlab.ui.control.Label
        CameraStream2Panel             matlab.ui.container.Panel
        CameraStream1Panel             matlab.ui.container.Panel
        TurretSlider                   matlab.ui.control.Slider
        TurretSliderLabel              matlab.ui.control.Label
        ElbowRollSlider                matlab.ui.control.Slider
        ElbowRollSliderLabel           matlab.ui.control.Label
        WristPitchSlider               matlab.ui.control.Slider
        WristPitchSliderLabel          matlab.ui.control.Label
        ElbowPitchSlider               matlab.ui.control.Slider
        ElbowPitchSliderLabel          matlab.ui.control.Label
        ShoulderPitchSlider            matlab.ui.control.Slider
        ShoulderPitchSliderLabel       matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DOF_Angles % a struct key:value for each joint
        Port %ESP32 PORT
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Port = serialport("/dev/cu.usbserial-0001",115200);
            
            app.DOF_Angles = struct( ...
            'shoulder_pitch', app.ShoulderPitchSlider.Value, ...
            'elbow_pitch', app.ElbowPitchSlider.Value, ...
            'wrist_pitch', app.WristPitchSlider.Value, ...
            'elbow_roll', app.ElbowRollSlider.Value, ...
            'turret', app.TurretSlider.Value, ...
            'end_effector', app.EndEffectorSlider.Value);            
        end

        % Value changed function: ElbowPitchSlider
        function ElbowPitchSliderValueChanged(app, event)
            app.DOF_Angles.elbow_pitch = app.ElbowPitchSlider.Value;
            disp(app.DOF_Angles)
            
        end

        % Value changed function: WristPitchSlider
        function WristPitchSliderValueChanged(app, event)
            app.DOF_Angles.wrist_pitch = app.WristPitchSlider.Value;
            disp(app.DOF_Angles)
            
        end

        % Value changed function: ElbowRollSlider
        function ElbowRollSliderValueChanged(app, event)
            app.DOF_Angles.elbow_roll = app.ElbowRollSlider.Value;
            disp(app.DOF_Angles)
            
            
        end

        % Value changed function: EndEffectorSlider
        function EndEffectorSliderValueChanged(app, event)
            app.DOF_Angles.end_effector = app.EndEffectorSlider.Value;
            disp(app.DOF_Angles)
            
        end

        % Button pushed function: ShoulderPitchIncrement
        function ShoulderPitchIncrementPushed(app, event)

            newAngle = min( ...
                app.ShoulderPitchSlider.Value +5, ...
                app.ShoulderPitchSlider.Limits(2)...
                );

            app.DOF_Angles.shoulder_pitch = newAngle;
            app.ShoulderPitchSlider.Value = newAngle;

            msg = jsonencode(app.DOF_Angles);
            writeline(app.Port,msg);

       
        end

        % Button pushed function: ShoulderPitchDecerment
        function ShoulderPitchDecermentButtonPushed(app, event)
            
            newAngle = max( ...
                app.ShoulderPitchSlider.Value -5, ...
                app.ShoulderPitchSlider.Limits(1)...
                );

            app.DOF_Angles.shoulder_pitch = newAngle;
            app.ShoulderPitchSlider.Value = newAngle;

            msg = jsonencode(app.DOF_Angles);
            writeline(app.Port,msg);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Port);
            delete(app)
            
        end

        % Value changing function: ShoulderPitchSlider
        function ShoulderPitchSliderValueChanging(app, event)
            app.DOF_Angles.shoulder_pitch = event.Value;

            msg = jsonencode(app.DOF_Angles);
            writeline(app.Port,msg);

            check = readline(app.Port);
            disp(check)
            
        end

        % Value changing function: TurretSlider
        function TurretSliderValueChanging(app, event)
            app.DOF_Angles.turret = event.Value;

            msg = jsonencode(app.DOF_Angles);
            writeline(app.Port,msg);
        end

        % Button pushed function: TurretIncrement
        function TurretIncrementPushed(app, event)
            newAngle = min( ...
                app.TurretSlider.Value +5, ...
                app.TurretSlider.Limits(2)...
                );

            app.DOF_Angles.turret = newAngle;
            app.TurretSlider.Value = newAngle;

            msg = jsonencode(app.DOF_Angles);
            writeline(app.Port,msg);
        end

        % Button pushed function: TurretDecrement
        function TurretDecrementPushed(app, event)
            newAngle = max( ...
                app.TurretSlider.Value -5, ...
                app.TurretSlider.Limits(1)...
                );

            app.DOF_Angles.turret = newAngle;
            app.TurretSlider.Value = newAngle;

            msg = jsonencode(app.DOF_Angles);
            writeline(app.Port,msg);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2D Linkage Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3D Linkage Graph')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ShoulderPitchSliderLabel = uilabel(app.UIFigure);
            app.ShoulderPitchSliderLabel.Text = 'Shoulder Pitch';
            app.ShoulderPitchSlider = uislider(app.UIFigure);
            app.ShoulderPitchSlider.Limits = [0 120];
            app.ShoulderPitchSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.ShoulderPitchSlider.ValueChangingFcn = createCallbackFcn(app, @ShoulderPitchSliderValueChanging, true);
            app.ShoulderPitchSlider.Value = 90;
            app.ElbowPitchSliderLabel = uilabel(app.UIFigure);
            app.ElbowPitchSliderLabel.Text = 'Elbow Pitch';
            app.ElbowPitchSlider = uislider(app.UIFigure);
            app.ElbowPitchSlider.Limits = [0 180];
            app.ElbowPitchSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.ElbowPitchSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowPitchSliderValueChanged, true);
            app.ElbowPitchSlider.Value = 90;
            app.WristPitchSliderLabel = uilabel(app.UIFigure);
            app.WristPitchSliderLabel.Text = 'Wrist Pitch';
            app.WristPitchSlider = uislider(app.UIFigure);
            app.WristPitchSlider.Limits = [0 180];
            app.WristPitchSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.WristPitchSlider.ValueChangedFcn = createCallbackFcn(app, @WristPitchSliderValueChanged, true);
            app.WristPitchSlider.Value = 90;
            app.ElbowRollSliderLabel = uilabel(app.UIFigure);
            app.ElbowRollSliderLabel.Text = 'Elbow Roll';
            app.ElbowRollSlider = uislider(app.UIFigure);
            app.ElbowRollSlider.Limits = [0 180];
            app.ElbowRollSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.ElbowRollSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowRollSliderValueChanged, true);
            app.ElbowRollSlider.Value = 90;
            app.TurretSliderLabel = uilabel(app.UIFigure);
            app.TurretSliderLabel.Text = 'Turret';
            app.TurretSlider = uislider(app.UIFigure);
            app.TurretSlider.Limits = [0 180];
            app.TurretSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.TurretSlider.ValueChangingFcn = createCallbackFcn(app, @TurretSliderValueChanging, true);
            app.TurretSlider.Value = 90;
            app.CameraStream1Panel = uipanel(app.UIFigure);
            app.CameraStream1Panel.Title = 'Camera Stream 1';
            app.CameraStream2Panel = uipanel(app.UIFigure);
            app.CameraStream2Panel.Title = 'Camera Stream 2';
            app.DetectedObjectsEditFieldLabel = uilabel(app.UIFigure);
            app.DetectedObjectsEditFieldLabel.Text = 'Detected Objects';
            app.DetectedObjectsEditField = uieditfield(app.UIFigure, 'text');
            app.HONGMK1ControlPanelLabel = uilabel(app.UIFigure);
            app.HONGMK1ControlPanelLabel.Text = 'HONG MK.1 Control Panel ';
            app.EndEffectorSliderLabel = uilabel(app.UIFigure);
            app.EndEffectorSliderLabel.Text = 'End Effector';
            app.EndEffectorSlider = uislider(app.UIFigure);
            app.EndEffectorSlider.Limits = [0 180];
            app.EndEffectorSlider.MajorTicks = [0 30 60 90 120 150 180];
            app.EndEffectorSlider.ValueChangedFcn = createCallbackFcn(app, @EndEffectorSliderValueChanged, true);
            app.EndEffectorSlider.Value = 90;
            app.ShoulderPitchDecerment = uibutton(app.UIFigure, 'push');
            app.ShoulderPitchDecerment.ButtonPushedFcn = createCallbackFcn(app, @ShoulderPitchDecermentButtonPushed, true);
            app.ShoulderPitchDecerment.Text = '-';
            app.ShoulderPitchIncrement = uibutton(app.UIFigure, 'push');
            app.ShoulderPitchIncrement.ButtonPushedFcn = createCallbackFcn(app, @ShoulderPitchIncrementPushed, true);
            app.ShoulderPitchIncrement.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '-';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '+';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '+';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '+';
            app.TurretDecrement = uibutton(app.UIFigure, 'push');
            app.TurretDecrement.ButtonPushedFcn = createCallbackFcn(app, @TurretDecrementPushed, true);
            app.TurretDecrement.Text = '-';
            app.TurretIncrement = uibutton(app.UIFigure, 'push');
            app.TurretIncrement.ButtonPushedFcn = createCallbackFcn(app, @TurretIncrementPushed, true);
            app.TurretIncrement.Text = '+';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '-';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HONGMK1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end